classdef MyFinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetButton                matlab.ui.control.Button
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        DataPlotButtonGroup        matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AvgCumulativeCases
        AvgCumulativeDeaths
        AvgDailyCases
        AvgDailyDeaths
        AvgWindow
        GlobalData
        Data
        ListOfCountries
        ShowCumilative
        ShowDaily
        ShowCases
        ShowDeaths
        ShowBoth
        TimePoints
        Title = []    
        TitlePlace = []
        TitleDataPlot = []
        TitleOption = []
        TitleAvgNo =[]
        CumulativeLeftAxisLimits   double 
        CumulativeRightAxisLimits  double
        DailyLeftAxisLimits        double
        DailyRightAxisLimits       double
    end
    
    methods (Access = public)
        function Default(app)
            app.CountryListBox.Items=[{'Global'},app.GlobalData.CountriesNames];
            app.CountryListBox.Value=app.CountryListBox.Items(1);
            app.StateorRegionListBox.Items={'All'};
            app.StateorRegionListBox.Value=app.StateorRegionListBox.Items(1);
            app.AveragedofdaysSlider.Value=1;
           
            
            app.CumulativeButton.Value=1;
            app.CasesButton.Value=1;
            app.ShowCumilative=1;
            app.ShowCases=1;
            app.ShowDeaths=0;
            app.ShowBoth=0;

            app.ComputeAverages();
            app.plotData();            

        end
        
        function BuildGlobalData(app)
          app.GlobalData=BuildGlobal(app.Data);
          app.TimePoints=datenum(app.GlobalData.Dates,'mm/dd/yy');
        end

        function ComputeAverages(app)
            [~,CountryIndex]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
            [~,StateIndex]=ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);
            app.AvgWindow=[round(app.AveragedofdaysSlider.Value)-1 0];

            if CountryIndex==1
%                app.CumulativeLeftAxisLimits=max(app.GlobalData.GlobalCumulativeCases)*1.1;
 %               app.CumulativeRightAxisLimits=max(app.GlobalData.GlobalCumulativeDeaths)*1.1;
  %              app.DailyLeftAxisLimits=max(app.GlobalData.GlobalDailyCases)*1.1;
   %             app.DailyRightAxisLimits=max(app.GlobalData.GlobalDailyDeaths)*1.1;


                app.AvgCumulativeCases=movmean(app.GlobalData.GlobalCumulativeCases,app.AvgWindow,'Endpoints','fill');
                app.AvgCumulativeDeaths=movmean(app.GlobalData.GlobalCumulativeDeaths,app.AvgWindow,'Endpoints','fill');
                app.AvgDailyCases=movmean(app.GlobalData.GlobalDailyCases,app.AvgWindow,'Endpoints','fill');
                app.AvgDailyDeaths=movmean(app.GlobalData.GlobalDailyDeaths,app.AvgWindow,'Endpoints','fill');
            else
                if StateIndex>1
    %                app.CumulativeLeftAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.CumulativeCases)*1.1;
     %               app.CumulativeRightAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.CumulativeDeaths)*1.1;
      %              app.DailyLeftAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.DailyCases)*1.1;
       %             app.DailyRightAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.DailyDeaths)*1.1;
                    
                    app.AvgCumulativeCases=movmean(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.CumulativeCases,app.AvgWindow,'Endpoints','fill');
                    app.AvgCumulativeDeaths=movmean(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.CumulativeDeaths,app.AvgWindow,'Endpoints','fill');
                    app.AvgDailyCases=movmean(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.DailyCases,app.AvgWindow,'Endpoints','fill');
                    app.AvgDailyDeaths=movmean(app.GlobalData.Countries{CountryIndex-1}.StatesList{StateIndex-1}.DailyDeaths,app.AvgWindow,'Endpoints','fill');
                else
        %            app.CumulativeLeftAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.CumulativeCases)*1.1;
         %           app.CumulativeRightAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.CumulativeDeaths)*1.1;
          %          app.DailyLeftAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.DailyCases)*1.1;
           %         app.DailyRightAxisLimits=max(app.GlobalData.Countries{CountryIndex-1}.DailyDeaths)*1.1;
                    
                    app.AvgCumulativeCases=movmean(app.GlobalData.Countries{CountryIndex-1}.CumulativeCases,app.AvgWindow,'Endpoints','fill');
                    app.AvgCumulativeDeaths=movmean(app.GlobalData.Countries{CountryIndex-1}.CumulativeDeaths,app.AvgWindow,'Endpoints','fill');
                    app.AvgDailyCases=movmean(app.GlobalData.Countries{CountryIndex-1}.DailyCases,app.AvgWindow,'Endpoints','fill');
                    app.AvgDailyDeaths=movmean(app.GlobalData.Countries{CountryIndex-1}.DailyDeaths,app.AvgWindow,'Endpoints','fill');
                end
            end


        end
        function plotData(app)
            cla(app.UIAxes, 'reset');          
           
                               
                if app.ShowCumilative

                    if app.ShowCases
                       
                        bar(app.UIAxes,app.TimePoints,app.AvgCumulativeCases);                        
                        ytickformat(app.UIAxes,'%d');
                        %ylim(app.UIAxes,[0 app.CumulativeLeftAxisLimits]);
                        app.UIAxes.YAxis.Exponent = 0;
                        %hold(app.UIAxes,"on");
                elseif app.ShowDeaths         
                   
                        plot(app.UIAxes,app.TimePoints,app.AvgCumulativeDeaths,'r','LineWidth',1.5);
                      % ylim(app.UIAxes,[0 app.CumulativeRightAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis.Exponent = 0;
                        %hold(app.UIAxes,"on");                        
                    else
                        yyaxis(app.UIAxes,"left");                        
                        bar(app.UIAxes,app.TimePoints,app.AvgCumulativeCases);
                        %ylim(app.UIAxes,[0 app.CumulativeLeftAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis(1).Exponent = 0;
                        hold(app.UIAxes,"on");

                        yyaxis(app.UIAxes,"right");                                                
                        plot(app.UIAxes,app.TimePoints,app.AvgCumulativeDeaths,'r','LineWidth',1.5);  
                        %ylim(app.UIAxes,[0 app.CumulativeRightAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis(2).Exponent = 0;
                    end
                else
                    if app.ShowCases      
                       
                        bar(app.UIAxes,app.TimePoints,app.AvgDailyCases);
                        %hold(app.UIAxes,"on");
                        %ylim(app.UIAxes,[0 app.DailyLeftAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis.Exponent = 0;
                   
                    elseif app.ShowDeaths
                       
                        plot(app.UIAxes,app.TimePoints,app.AvgDailyDeaths,'r','LineWidth',1.5);
                        app.UIAxes.YAxis.Exponent=0;
                        %ylim(app.UIAxes,[0 app.DailyRightAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis.Exponent = 0;
                    else
                        yyaxis(app.UIAxes,"left");                        
                        bar(app.UIAxes,app.TimePoints,app.AvgDailyCases);
                        %ylim(app.UIAxes,[0 app.DailyLeftAxisLimits]);
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis(1).Exponent = 0;
                        hold(app.UIAxes,"on");

                        yyaxis(app.UIAxes,"right");                           
                        plot(app.UIAxes,app.TimePoints,app.AvgDailyDeaths,'r','LineWidth',1.5);
                        %ylim(app.UIAxes,[0 app.DailyRightAxisLimits]);                        
                        ytickformat(app.UIAxes,'%d');
                        app.UIAxes.YAxis(2).Exponent = 0;
                        

                    end

                end

            title(app.UIAxes,app.GenerateTitle());

            grid(app.UIAxes,'on');          
            datetick(app.UIAxes,'x','mmmdd')
           
            hold(app.UIAxes,"off");

            
        end
        function title =GenerateTitle(app)
            [~,CountryIndex]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
            [~,StateIndex]=ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);
            
            if CountryIndex==1
                app.TitlePlace=' Globally';
            else              
                if StateIndex>1
                    app.TitlePlace=[' in ' , app.StateorRegionListBox.Value];
                else
                    app.TitlePlace=[' in ',app.CountryListBox.Value];
                end
            end

            if app.ShowCumilative
                app.TitleOption='Cumulative';
            else
               app.TitleOption='Daily';
               
            end

         if app.ShowCases
             app.TitleDataPlot='Cases';
         elseif app.ShowDeaths
             app.TitleDataPlot='Deaths';
         else
             app.TitleDataPlot='Cases & Deaths';
         end

         if round(app.AveragedofdaysSlider.Value)>1
            app.TitleAvgNo=[' (',num2str(round(app.AveragedofdaysSlider.Value)),'-day mean)'];
         end
            
         title=[app.TitleOption,' Number of ',app.TitleDataPlot, app.TitlePlace,app.TitleAvgNo];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
             load covid_data2.mat covid_data;
            app.Data=covid_data;
            app.BuildGlobalData();   
            app.Default();           
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            [~,index]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
            if index==1
                app.StateorRegionListBox.Items={'All'};
                app.StateorRegionListBox.Value=app.StateorRegionListBox.Items(1);
            else
                app.StateorRegionListBox.Items=[{'All'},app.GlobalData.Countries{index-1}.StatesListNames];
                app.StateorRegionListBox.Value=app.StateorRegionListBox.Items(1);
            end
            app.ComputeAverages();
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCumilative=app.CumulativeButton.Value;
            app.ShowDaily=app.DailyButton.Value;            
            app.ComputeAverages();
            app.plotData();


        end

        % Selection changed function: DataPlotButtonGroup
        function DataPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases=app.CasesButton.Value;
            app.ShowDeaths=app.DeathsButton.Value;
            app.ShowBoth=app.BothButton.Value;

            app.ComputeAverages();
            app.plotData();
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.ComputeAverages();
            app.plotData();
            
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.TitleAvgNo=[];
            app.ComputeAverages();
            app.plotData();          
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TitleAvgNo=[];
            app.Default()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization(Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AveragedofdaysSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DataPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DataPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataPlotButtonGroupSelectionChanged, true);
            app.DataPlotButtonGroup.Title = 'Data Plot';
            app.CasesButton = uiradiobutton(app.DataPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DataPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or'; '  Region:'; ''};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyFinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end